classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        HomeTab                       matlab.ui.container.Tab
        ChefsThirdArmLabel            matlab.ui.control.Label
        ESTOPButton                   matlab.ui.control.StateButton
        ShelfCapacityPanel            matlab.ui.container.Panel
        BottomGaugeLabel              matlab.ui.control.Label
        BottomGauge                   matlab.ui.control.LinearGauge
        TopGaugeLabel                 matlab.ui.control.Label
        TopGauge                      matlab.ui.control.LinearGauge
        Step1StoreIngredientPanel     matlab.ui.container.Panel
        ItemnameEditFieldLabel        matlab.ui.control.Label
        ItemnameEditField             matlab.ui.control.EditField
        ContainertypeDropDownLabel    matlab.ui.control.Label
        ContainertypeDropDown         matlab.ui.control.DropDown
        StoreButton                   matlab.ui.control.Button
        ContainerTypesPanel           matlab.ui.container.Panel
        Image                         matlab.ui.control.Image
        Image_2                       matlab.ui.control.Image
        Image_3                       matlab.ui.control.Image
        Image_4                       matlab.ui.control.Image
        Step2RetrieveIngredientPanel  matlab.ui.container.Panel
        RetrieveItemButton            matlab.ui.control.Button
        RetrieveitemDropDownLabel     matlab.ui.control.Label
        RetrieveitemDropDown          matlab.ui.control.DropDown
        CurrentStatusPanel            matlab.ui.container.Panel
        LampLabel                     matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        TextArea                      matlab.ui.control.TextArea
        SimulationSwitchLabel         matlab.ui.control.Label
        SimulationSwitch              matlab.ui.control.Switch
        RemoteControlSwitchLabel      matlab.ui.control.Label
        RemoteControlSwitch           matlab.ui.control.Switch
        Panel                         matlab.ui.container.Panel
        ManualControlTab              matlab.ui.container.Tab
        ESTOPButton2                  matlab.ui.control.StateButton
        ChefsThirdArmManualControlLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
            controller;
            workspaceSize = [-1, 1, -1, 1, -0.012, 1];
            dobotBaseTransform = transl(0,0,0);
            simulationToggle = 1;
            realRobotToggle = 0;
            eStopToggle = 0;
    end
    
    methods (Access = private)
        
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function workspaceInit(app)
            clf;
            workspace = Workspace(app.realRobotToggle, app.simulationToggle, app.dobotBaseTransform, app.workspaceSize);
            ROSCom = RosPublish(app.realRobotToggle);
            app.controller = Controller(workspace, ROSCom);

        end

        % Button pushed function: StoreButton
        function StoreButtonPressed(app, event)
            if app.eStopToggle == 1
                return
            end
            
            containerType = str2num(app.ContainertypeDropDown.Value);
            
            app.TextArea.Value = 'Storing ingredient in progess...';
            app.Lamp.Color = '#EDB120';
            
%             if app.ItemnameEditField.Value == 1
%                 app.Image.BackgroundColor = 'green';
%                 
%             end
                      
            app.controller.StoreContainer(app.ItemnameEditField.Value, containerType);
            
            n = size(app.controller.workspace1.containerStorage, 2);
            for i=1:n
                app.RetrieveitemDropDown.Items(i) = {app.controller.workspace1.containerStorage(i).label};
            end
            
            %capacityShelf1 = bottom shelf, capacityshelf2 = top shelf
            [capacityShelf1, capacityShelf2] = app.controller.GetCapacityStatus();
            app.TopGauge.Value = 5*capacityShelf2;
            app.BottomGauge.Value = 5*capacityShelf1;
            
            app.TextArea.Value = 'Waiting for user command...';
            app.Lamp.Color = 'green';
        end

        % Button pushed function: RetrieveItemButton
        function RetrieveButtonPressed(app, event)
            if app.eStopToggle == 1
                return
            end
            
            app.TextArea.Value = 'Retrieving ingredient in progess...';
            app.Lamp.Color = '#EDB120';
            
            app.controller.RetrieveContainer(app.RetrieveitemDropDown.Value);
            
            [~,idx] = ismember(app.RetrieveitemDropDown.Value,app.RetrieveitemDropDown.Items);
            app.RetrieveitemDropDown.Items(idx) = [];
            
            %clear drop down to overwrite
            n = size(app.controller.workspace1.containerStorage, 2);
            for i=1:n
                app.RetrieveitemDropDown.Items(i) = {app.controller.workspace1.containerStorage(i).label};
            end
           
            app.TextArea.Value = 'Waiting for user command...';
            app.Lamp.Color = 'green';
        end

        % Value changed function: ESTOPButton
        function ESTOPButtonPressed(app, event)
            if app.ESTOPButton.Value == true
                app.eStopToggle = 1;
                app.TextArea.Value = 'ESTOP activation - operations stopped';
                app.Lamp.Color = 'red';
                app.controller.EmergencyStop();
            end
            if app.ESTOPButton.Value == false
                app.eStopToggle = 0;
                app.TextArea.Value = 'Operations resumed...';
                app.Lamp.Color = '#EDB120';
                app.controller.ClearEmergencyStop();
            end
        end

        % Value changed function: ESTOPButton2
        function ESTOP2ButtonPressed(app, event)
            if app.ESTOPButton.Value == true
                app.eStopToggle = 1;
                app.TextArea.Value = 'ESTOP activation - operations stopped';
                app.Lamp.Color = 'red';
                app.controller.EmergencyStop();
            end
            if app.ESTOPButton.Value == false
                app.eStopToggle = 0;
                app.TextArea.Value = 'Operations resumed...';
                app.Lamp.Color = '#EDB120';
                app.controller.ClearEmergencyStop();
            end
            end
        end

        % Value changed function: ContainertypeDropDown
        function ValueChangedFcn(app, event)
            val = app.ContainertypeDropDown.Value;
            switch val
                case val==1
                app.Image.BackgroundColor = 'green';
                app.Image_2.BackgroundColor = 'none';
                app.Image_3.BackgroundColor = 'none';
                app.Image_4.BackgroundColor = 'none';
                case val==2
                app.Image.BackgroundColor = 'none';
                app.Image_2.BackgroundColor = 'green';
                app.Image_3.BackgroundColor = 'none';
                app.Image_4.BackgroundColor = 'none';
                case val==3
                app.Image.BackgroundColor = 'none';
                app.Image_2.BackgroundColor = 'none';
                app.Image_3.BackgroundColor = 'green';
                app.Image_4.BackgroundColor = 'none';
                case val==4
                app.Image.BackgroundColor = 'green';
                app.Image_2.BackgroundColor = 'none';
                app.Image_3.BackgroundColor = 'none';
                app.Image_4.BackgroundColor = 'green';
                otherwise
                app.Image.BackgroundColor = 'none';    
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Tag = 'gui_tag';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.ChefsThirdArmLabel = uilabel(app.HomeTab);
            app.ChefsThirdArmLabel.Text = 'Chef''s Third Arm';
            app.ESTOPButton = uibutton(app.HomeTab, 'state');
            app.ESTOPButton.ValueChangedFcn = createCallbackFcn(app, @ESTOPButtonPressed, true);
            app.ESTOPButton.Text = 'E-STOP';
            app.ShelfCapacityPanel = uipanel(app.HomeTab);
            app.ShelfCapacityPanel.Title = 'Shelf Capacity';
            app.BottomGaugeLabel = uilabel(app.ShelfCapacityPanel);
            app.BottomGaugeLabel.Text = 'Bottom';
            app.BottomGauge = uigauge(app.ShelfCapacityPanel, 'linear');
            app.BottomGauge.Limits = [0 6];
            app.BottomGauge.MajorTicks = [0 1 2 3 4 5 6];
            app.BottomGauge.MajorTickLabels = {'Empty', '1', '2', '3', '4', '5', 'Full'};
            app.BottomGauge.MinorTicks = [];
            app.TopGaugeLabel = uilabel(app.ShelfCapacityPanel);
            app.TopGaugeLabel.Text = 'Top';
            app.TopGauge = uigauge(app.ShelfCapacityPanel, 'linear');
            app.TopGauge.Limits = [0 6];
            app.TopGauge.MajorTicks = [0 1 2 3 4 5 6];
            app.TopGauge.MajorTickLabels = {'Empty', '1', '2', '3', '4', '5', 'Full'};
            app.TopGauge.MinorTicks = [];
            app.Step1StoreIngredientPanel = uipanel(app.HomeTab);
            app.Step1StoreIngredientPanel.Title = 'Step 1 - Store Ingredient';
            app.ItemnameEditFieldLabel = uilabel(app.Step1StoreIngredientPanel);
            app.ItemnameEditFieldLabel.Text = 'Item name';
            app.ItemnameEditField = uieditfield(app.Step1StoreIngredientPanel, 'text');
            app.ContainertypeDropDownLabel = uilabel(app.Step1StoreIngredientPanel);
            app.ContainertypeDropDownLabel.Text = 'Container type';
            app.ContainertypeDropDown = uidropdown(app.Step1StoreIngredientPanel);
            app.ContainertypeDropDown.Items = {'1', '2', '3', '4'};
            app.ContainertypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ValueChangedFcn, true);
            app.ContainertypeDropDown.Value = '1';
            app.StoreButton = uibutton(app.Step1StoreIngredientPanel, 'push');
            app.StoreButton.ButtonPushedFcn = createCallbackFcn(app, @StoreButtonPressed, true);
            app.StoreButton.Text = 'Store';
            app.ContainerTypesPanel = uipanel(app.HomeTab);
            app.ContainerTypesPanel.Title = 'Container Types';
            app.Image = uiimage(app.ContainerTypesPanel);
            app.Image.ImageSource = 'type1.jpg';
            app.Image_2 = uiimage(app.ContainerTypesPanel);
            app.Image_2.ImageSource = 'type2.jpg';
            app.Image_3 = uiimage(app.ContainerTypesPanel);
            app.Image_3.ImageSource = 'type1.jpg';
            app.Image_4 = uiimage(app.ContainerTypesPanel);
            app.Image_4.ImageSource = 'type4.jpg';
            app.Step2RetrieveIngredientPanel = uipanel(app.HomeTab);
            app.Step2RetrieveIngredientPanel.Title = 'Step 2 - Retrieve Ingredient';
            app.RetrieveItemButton = uibutton(app.Step2RetrieveIngredientPanel, 'push');
            app.RetrieveItemButton.ButtonPushedFcn = createCallbackFcn(app, @RetrieveButtonPressed, true);
            app.RetrieveItemButton.Text = 'Retrieve Item';
            app.RetrieveitemDropDownLabel = uilabel(app.Step2RetrieveIngredientPanel);
            app.RetrieveitemDropDownLabel.Text = 'Retrieve item';
            app.RetrieveitemDropDown = uidropdown(app.Step2RetrieveIngredientPanel);
            app.RetrieveitemDropDown.Items = {};
            app.RetrieveitemDropDown.Value = {};
            app.CurrentStatusPanel = uipanel(app.HomeTab);
            app.CurrentStatusPanel.Title = 'Current Status';
            app.LampLabel = uilabel(app.CurrentStatusPanel);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.CurrentStatusPanel);
            app.TextArea = uitextarea(app.CurrentStatusPanel);
            app.TextArea.Value = {'Waiting for user command...'; ''};
            app.SimulationSwitchLabel = uilabel(app.HomeTab);
            app.SimulationSwitchLabel.Text = 'Simulation';
            app.SimulationSwitch = uiswitch(app.HomeTab, 'slider');
            app.SimulationSwitch.Items = {'On', 'Off'};
            app.SimulationSwitch.Value = 'On';
            app.RemoteControlSwitchLabel = uilabel(app.HomeTab);
            app.RemoteControlSwitchLabel.Text = 'Remote Control';
            app.RemoteControlSwitch = uiswitch(app.HomeTab, 'slider');
            app.Panel = uipanel(app.HomeTab);
            app.Panel.Title = 'Panel';
            app.ManualControlTab = uitab(app.TabGroup);
            app.ManualControlTab.Title = 'Manual Control';
            app.ESTOPButton2 = uibutton(app.ManualControlTab, 'state');
            app.ESTOPButton2.ValueChangedFcn = createCallbackFcn(app, @ESTOP2ButtonPressed, true);
            app.ESTOPButton2.Text = 'E-STOP';
            app.ChefsThirdArmManualControlLabel = uilabel(app.ManualControlTab);
            app.ChefsThirdArmManualControlLabel.Text = 'Chef''s Third Arm - Manual Control';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @workspaceInit)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end