classdef gui_kinect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        ChefsThirdArmLabel              matlab.ui.control.Label
        ShelfCapacityPanel              matlab.ui.container.Panel
        BottomGaugeLabel                matlab.ui.control.Label
        BottomGauge                     matlab.ui.control.LinearGauge
        TopGaugeLabel                   matlab.ui.control.Label
        TopGauge                        matlab.ui.control.LinearGauge
        Step1StoreIngredientPanel       matlab.ui.container.Panel
        ItemnameEditFieldLabel          matlab.ui.control.Label
        ItemnameEditField               matlab.ui.control.EditField
        ContainertypeDropDownLabel      matlab.ui.control.Label
        ContainertypeDropDown           matlab.ui.control.DropDown
        StoreButton                     matlab.ui.control.Button
        ContainerTypesPanel             matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        Image_4                         matlab.ui.control.Image
        Step2RetrieveIngredientPanel    matlab.ui.container.Panel
        RetrieveItemButton              matlab.ui.control.Button
        RetrieveitemDropDownLabel       matlab.ui.control.Label
        RetrieveitemDropDown            matlab.ui.control.DropDown
        CurrentStatusPanel              matlab.ui.container.Panel
        StatusLamp                      matlab.ui.control.Lamp
        TextArea                        matlab.ui.control.TextArea
        EEControlSwitchPanel            matlab.ui.container.Panel
        RemoteControlSwitchLabel        matlab.ui.control.Label
        RemoteControlSwitch             matlab.ui.control.Switch
        ToggleTextArea                  matlab.ui.control.TextArea
        ToggleLamp                      matlab.ui.control.Lamp
        ResumeButton                    matlab.ui.control.Button
        eStopButton                     matlab.ui.control.Button
        RobotControlTab                 matlab.ui.container.Tab
        eStopButton_2                   matlab.ui.control.Button
        ResumeButton_2                  matlab.ui.control.Button
        ChefsThirdArmRobotControlLabel  matlab.ui.control.Label
        JointControlSwitchPanel         matlab.ui.container.Panel
        RemoteControlSwitch_2Label      matlab.ui.control.Label
        JointControlSwitch              matlab.ui.control.Switch
        JointControlText                matlab.ui.control.TextArea
        JointControlLamp                matlab.ui.control.Lamp
        ActiveJointPanel                matlab.ui.container.Panel
        ServoLamp                       matlab.ui.control.Lamp
        BaseLamp                        matlab.ui.control.Lamp
        ForearmLamp                     matlab.ui.control.Lamp
        ReararmLamp                     matlab.ui.control.Lamp
        LinearRailLabel                 matlab.ui.control.Label
        BaseJointLabel                  matlab.ui.control.Label
        ForearmJointLabel               matlab.ui.control.Label
        ReararmJointLabel               matlab.ui.control.Label
        ServoLabel                      matlab.ui.control.Label
        LinearLamp                      matlab.ui.control.Lamp
        Panel                           matlab.ui.container.Panel
        Image2                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        LinearRailLabel_2               matlab.ui.control.Label
        ForearmJointLabel_2             matlab.ui.control.Label
        ServoLabel_2                    matlab.ui.control.Label
        ReararmJointLabel_2             matlab.ui.control.Label
        BaseJointLabel_2                matlab.ui.control.Label
    end

    
    properties (Access = private)
            controller;
            workspaceSize = [-1, 1, -1, 1, -0.012, 1];
            dobotBaseTransform = transl(0,0,0);
            simulationToggle = 1;
            realRobotToggle = 0;
            eStopToggle = 0;
            busyToggle = 0;
            remoteToggle = 0;
    end
    
    methods (Access = private)
        
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function workspaceInit(app)
            clf;
            workspace = Workspace(app.realRobotToggle, app.simulationToggle, app.dobotBaseTransform, app.workspaceSize);
            ROSCom = RosPublish(app.realRobotToggle);
            app.controller = Controller(workspace, ROSCom);
            kinect = 

        end

        % Button pushed function: StoreButton
        function StoreButtonPressed(app, event)
            % eStop pauses all activity
            if (app.eStopToggle ~= 0) || (app.busyToggle == 1)
                return
            end
            app.busyToggle = 1;
            
            containerType = str2num(app.ContainertypeDropDown.Value);
            
            %set status lamp colour and text
            app.TextArea.Value = 'Storing ingredient in progess...';
            app.StatusLamp.Color = '#EDB120';
            
            if containerType == '2' 
                app.Image.BackgroundColor = 'none';
                app.Image_2.BackgroundColor = 'green';
                app.Image_3.BackgroundColor = 'none';
                app.Image_4.BackgroundColor = 'none';                
            elseif containerType == '3' 
                app.Image.BackgroundColor = 'none';
                app.Image_2.BackgroundColor = 'none';
                app.Image_3.BackgroundColor = 'green';
                app.Image_4.BackgroundColor = 'none';                
            elseif containerType == '4' 
                app.Image.BackgroundColor = 'green';
                app.Image_2.BackgroundColor = 'none';
                app.Image_3.BackgroundColor = 'none';
                app.Image_4.BackgroundColor = 'green';                
            elseif containerType == '1' 
                app.Image.BackgroundColor = 'green';
                app.Image_2.BackgroundColor = 'none';
                app.Image_3.BackgroundColor = 'none';
                app.Image_4.BackgroundColor = 'none';   
            end
            
            %function to store container
            app.controller.StoreContainer(app.ItemnameEditField.Value, containerType);
            
            n = size(app.controller.workspace1.containerStorage, 2);
            for i=1:n
                app.RetrieveitemDropDown.Items(i) = {app.controller.workspace1.containerStorage(i).label};
            end
            
            %capacityShelf1 = bottom shelf, capacityshelf2 = top shelf
            [capacityShelf1, capacityShelf2] = app.controller.GetCapacityStatus();
            app.TopGauge.Value = 5*capacityShelf2;
            app.BottomGauge.Value = 5*capacityShelf1;
            
            %set status lamp colour and text
            app.TextArea.Value = 'Waiting for user command...';
            app.StatusLamp.Color = 'green';
            
            app.busyToggle = 0;
        end

        % Button pushed function: RetrieveItemButton
        function RetrieveButtonPressed(app, event)
            % eStop pauses all activity
            if (app.eStopToggle ~= 0) || (app.busyToggle == 1)
                return
            end
            app.busyToggle = 1;
            
            %set status lamp colour and text
            app.TextArea.Value = 'Retrieving ingredient in progess...';
            app.StatusLamp.Color = '#EDB120';
            
            %function to retrieve container
            app.controller.RetrieveContainer(app.RetrieveitemDropDown.Value);
            
            %clear drop down and overwrite with updated container capacity
            [~,idx] = ismember(app.RetrieveitemDropDown.Value,app.RetrieveitemDropDown.Items);
            app.RetrieveitemDropDown.Items(idx) = [];

            n = size(app.controller.workspace1.containerStorage, 2);
            for i=1:n
                app.RetrieveitemDropDown.Items(i) = {app.controller.workspace1.containerStorage(i).label};
            end
           
            %set status lamp colour and text
            app.TextArea.Value = 'Waiting for user command...';
            app.StatusLamp.Color = 'green';
            
            app.busyToggle = 0;
        end

        % Value changed function: ContainertypeDropDown
        function ValueChangedFcn(app, event)
            % 'select' container type image based on drop down value
            switch app.ContainertypeDropDown.Value
                case '1'
                    app.Image.BackgroundColor = 'green';
                    app.Image_2.BackgroundColor = 'none';
                    app.Image_3.BackgroundColor = 'none';
                    app.Image_4.BackgroundColor = 'none';                
                case '2'
                    app.Image.BackgroundColor = 'none';
                    app.Image_2.BackgroundColor = 'green';
                    app.Image_3.BackgroundColor = 'none';
                    app.Image_4.BackgroundColor = 'none';                
                case '3'
                    app.Image.BackgroundColor = 'none';
                    app.Image_2.BackgroundColor = 'none';
                    app.Image_3.BackgroundColor = 'green';
                    app.Image_4.BackgroundColor = 'none';                
                case '4'
                    app.Image.BackgroundColor = 'none';
                    app.Image_2.BackgroundColor = 'none';
                    app.Image_3.BackgroundColor = 'none';
                    app.Image_4.BackgroundColor = 'green';   
            end
        
        end

        % Value changed function: RemoteControlSwitch
        function ControlToggled(app, event)
            if (app.eStopToggle ~= 0) || ((app.busyToggle == 1) && (app.remoteToggle == 0))
                return
            end
            app.busyToggle = 1;
            
             switch app.RemoteControlSwitch.Value
                case 'On'
                    app.remoteToggle = 1;
                    %app.controllerToggle = 1;
                    %set status lamp colour and text
                    app.StatusLamp.Color = '#EDB120';
                    app.TextArea.Value = 'Manual control mode activated...';
                    %if for jog or end effector toggle
                    app.ToggleTextArea.Value = 'Manual control mode activated, refer to Robot Control tab';
                    app.StatusLamp.Color = '#EDB120';
                    app.ToggleLamp.Color = 'green'; %on
                    app.controller.StartRemoteControl();
                case 'Off'
                    app.remoteToggle = 0;
                    app.ToggleTextArea.Value = '';
                    app.ToggleLamp.Color = 'white'; %off
                    app.TextArea.Value = 'Waiting for user command...';
                    app.StatusLamp.Color = 'green';
                    app.controller.EndRemoteControl();
             end
            
            app.busyToggle = 0;
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPressed(app, event)
             if (app.eStopToggle == 2)
                app.eStopToggle = 0;
                app.TextArea.Value = 'Operations resumed...';
                app.StatusLamp.Color = '#EDB120';
                app.controller.ClearEmergencyStop();
             end
        end

        % Button pushed function: eStopButton
        function eStopButtonPressed(app, event)
              % eStop pauses all activity
              switch (app.eStopToggle)
                  case 0
                        app.eStopToggle = 1;
                        app.TextArea.Value = 'ESTOP activated - press again to deactivate';
                        app.StatusLamp.Color = 'red';
                        app.controller.EmergencyStop();
                  case 1
                        app.eStopToggle = 2;
                        app.TextArea.Value = 'ESTOP deactivated - press RESUME to continue operations';
                        app.StatusLamp.Color = 'yellow';
                  case 2
                        app.eStopToggle = 1;
                        app.TextArea.Value = 'ESTOP activation - press again to deactivate';
                        app.StatusLamp.Color = 'red';
                        app.controller.EmergencyStop();
                  otherwise
                        app.eStopToggle = 1;
                        app.TextArea.Value = 'ESTOP activation - press again to deactivate';
                        app.StatusLamp.Color = 'red';
                        app.controller.EmergencyStop();
              end
                
        end

        % Value changed function: JointControlSwitch
        function JointControlToggled(app, event)
            if (app.eStopToggle ~= 0) || ((app.busyToggle == 1) && (app.remoteToggle == 0))
                return
            end
            app.busyToggle = 1;
            
            switch app.JointControlSwitch.Value
                case 'On'
                    
                    app.controller.StartRemoteControl(1,0);
                    app.ToggleTextArea.Value = 'Manual control mode activated, refer to Robot Control tab';
                    app.StatusLamp.Color = '#EDB120';
                    app.JointControlLamp.Color = '#EDB120';
                    app.JointControlText = 'Robot currently controlled by joystick...'
                    app.StatusLamp.Color = '#EDB120';
                    app.TextArea.Value = 'Manual control mode activated...';
                case 'Off'
                    app.controller.EndRemoteControl();
                    app.ToggleTextArea.Value = '';
                    app.TextArea.Value = 'Waiting for user command...';
                    app.StatusLamp.Color = 'green';
                    app.JointControlLamp.Color = 'white';
                    app.JointControlText = ''
                    app.busyToggle = 0;
            end
            
        end

        % Button pushed function: ResumeButton_2
        function ResumeButton2Pressed(app, event)
             if (app.eStopToggle == 2)
                app.eStopToggle = 0;
                app.TextArea.Value = 'Operations resumed...';
                app.StatusLamp.Color = '#EDB120';
                app.controller.ClearEmergencyStop();
             end
        end

        % Button pushed function: eStopButton_2
        function eStop2ButtonPressed(app, event)
              % eStop pauses all activity
              switch (app.eStopToggle)
                  case 0
                        app.eStopToggle = 1;
                        app.TextArea.Value = 'ESTOP activated - press again to deactivate';
                        app.StatusLamp.Color = 'red';
                        app.controller.EmergencyStop();
                  case 1
                        app.eStopToggle = 2;
                        app.TextArea.Value = 'ESTOP deactivated - press RESUME to continue operations';
                        app.StatusLamp.Color = 'yellow';
                  case 2
                        app.eStopToggle = 1;
                        app.TextArea.Value = 'ESTOP activation - press again to deactivate';
                        app.StatusLamp.Color = 'red';
                        app.controller.EmergencyStop();
                  otherwise
                        app.eStopToggle = 1;
                        app.TextArea.Value = 'ESTOP activation - press again to deactivate';
                        app.StatusLamp.Color = 'red';
                        app.controller.EmergencyStop();
              end
             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Tag = 'gui_tag';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.ChefsThirdArmLabel = uilabel(app.HomeTab);
            app.ChefsThirdArmLabel.Text = 'Chef''s Third Arm';
            app.ShelfCapacityPanel = uipanel(app.HomeTab);
            app.ShelfCapacityPanel.Title = 'Shelf Capacity';
            app.BottomGaugeLabel = uilabel(app.ShelfCapacityPanel);
            app.BottomGaugeLabel.Text = 'Bottom';
            app.BottomGauge = uigauge(app.ShelfCapacityPanel, 'linear');
            app.BottomGauge.Limits = [0 6];
            app.BottomGauge.MajorTicks = [0 1 2 3 4 5 6];
            app.BottomGauge.MajorTickLabels = {'Empty', '1', '2', '3', '4', '5', 'Full'};
            app.BottomGauge.MinorTicks = [];
            app.TopGaugeLabel = uilabel(app.ShelfCapacityPanel);
            app.TopGaugeLabel.Text = 'Top';
            app.TopGauge = uigauge(app.ShelfCapacityPanel, 'linear');
            app.TopGauge.Limits = [0 6];
            app.TopGauge.MajorTicks = [0 1 2 3 4 5 6];
            app.TopGauge.MajorTickLabels = {'Empty', '1', '2', '3', '4', '5', 'Full'};
            app.TopGauge.MinorTicks = [];
            app.Step1StoreIngredientPanel = uipanel(app.HomeTab);
            app.Step1StoreIngredientPanel.Title = 'Step 1 - Store Ingredient';
            app.ItemnameEditFieldLabel = uilabel(app.Step1StoreIngredientPanel);
            app.ItemnameEditFieldLabel.Text = 'Item name';
            app.ItemnameEditField = uieditfield(app.Step1StoreIngredientPanel, 'text');
            app.ContainertypeDropDownLabel = uilabel(app.Step1StoreIngredientPanel);
            app.ContainertypeDropDownLabel.Text = 'Container type';
            app.ContainertypeDropDown = uidropdown(app.Step1StoreIngredientPanel);
            app.ContainertypeDropDown.Items = {'1', '2', '3', '4'};
            app.ContainertypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ValueChangedFcn, true);
            app.ContainertypeDropDown.Value = '1';
            app.StoreButton = uibutton(app.Step1StoreIngredientPanel, 'push');
            app.StoreButton.ButtonPushedFcn = createCallbackFcn(app, @StoreButtonPressed, true);
            app.StoreButton.Text = 'Store';
            app.ContainerTypesPanel = uipanel(app.HomeTab);
            app.ContainerTypesPanel.Title = 'Container Types';
            app.Image = uiimage(app.ContainerTypesPanel);
            app.Image.ImageSource = 'type1.jpg';
            app.Image_2 = uiimage(app.ContainerTypesPanel);
            app.Image_2.ImageSource = 'type2.jpg';
            app.Image_3 = uiimage(app.ContainerTypesPanel);
            app.Image_3.ImageSource = 'type3.jpg';
            app.Image_4 = uiimage(app.ContainerTypesPanel);
            app.Image_4.ImageSource = 'type4.jpg';
            app.Step2RetrieveIngredientPanel = uipanel(app.HomeTab);
            app.Step2RetrieveIngredientPanel.Title = 'Step 2 - Retrieve Ingredient';
            app.RetrieveItemButton = uibutton(app.Step2RetrieveIngredientPanel, 'push');
            app.RetrieveItemButton.ButtonPushedFcn = createCallbackFcn(app, @RetrieveButtonPressed, true);
            app.RetrieveItemButton.Text = 'Retrieve Item';
            app.RetrieveitemDropDownLabel = uilabel(app.Step2RetrieveIngredientPanel);
            app.RetrieveitemDropDownLabel.Text = 'Retrieve item';
            app.RetrieveitemDropDown = uidropdown(app.Step2RetrieveIngredientPanel);
            app.RetrieveitemDropDown.Items = {};
            app.RetrieveitemDropDown.Value = {};
            app.CurrentStatusPanel = uipanel(app.HomeTab);
            app.CurrentStatusPanel.Title = 'Current Status';
            app.StatusLamp = uilamp(app.CurrentStatusPanel);
            app.TextArea = uitextarea(app.CurrentStatusPanel);
            app.TextArea.Value = {'Waiting for user command...'; ''};
            app.EEControlSwitchPanel = uipanel(app.HomeTab);
            app.EEControlSwitchPanel.Title = 'E-E Control Switch';
            app.RemoteControlSwitchLabel = uilabel(app.EEControlSwitchPanel);
            app.RemoteControlSwitchLabel.Text = 'Remote Control';
            app.RemoteControlSwitch = uiswitch(app.EEControlSwitchPanel, 'slider');
            app.RemoteControlSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlToggled, true);
            app.ToggleTextArea = uitextarea(app.EEControlSwitchPanel);
            app.ToggleLamp = uilamp(app.EEControlSwitchPanel);
            app.ResumeButton = uibutton(app.HomeTab, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPressed, true);
            app.ResumeButton.Text = 'Resume';
            app.eStopButton = uibutton(app.HomeTab, 'push');
            app.eStopButton.ButtonPushedFcn = createCallbackFcn(app, @eStopButtonPressed, true);
            app.eStopButton.Text = 'E-STOP';
            app.RobotControlTab = uitab(app.TabGroup);
            app.RobotControlTab.Title = 'Robot Control';
            app.eStopButton_2 = uibutton(app.RobotControlTab, 'push');
            app.eStopButton_2.ButtonPushedFcn = createCallbackFcn(app, @eStop2ButtonPressed, true);
            app.eStopButton_2.Text = 'E-STOP';
            app.ResumeButton_2 = uibutton(app.RobotControlTab, 'push');
            app.ResumeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResumeButton2Pressed, true);
            app.ResumeButton_2.Text = 'Resume';
            app.ChefsThirdArmRobotControlLabel = uilabel(app.RobotControlTab);
            app.ChefsThirdArmRobotControlLabel.Text = 'Chef''s Third Arm - Robot Control';
            app.JointControlSwitchPanel = uipanel(app.RobotControlTab);
            app.JointControlSwitchPanel.Title = 'Joint Control Switch';
            app.RemoteControlSwitch_2Label = uilabel(app.JointControlSwitchPanel);
            app.RemoteControlSwitch_2Label.Text = 'Remote Control';
            app.JointControlSwitch = uiswitch(app.JointControlSwitchPanel, 'slider');
            app.JointControlSwitch.ValueChangedFcn = createCallbackFcn(app, @JointControlToggled, true);
            app.JointControlText = uitextarea(app.JointControlSwitchPanel);
            app.JointControlLamp = uilamp(app.JointControlSwitchPanel);
            app.ActiveJointPanel = uipanel(app.RobotControlTab);
            app.ActiveJointPanel.Title = 'Active Joint';
            app.ServoLamp = uilamp(app.ActiveJointPanel);
            app.BaseLamp = uilamp(app.ActiveJointPanel);
            app.ForearmLamp = uilamp(app.ActiveJointPanel);
            app.ReararmLamp = uilamp(app.ActiveJointPanel);
            app.LinearRailLabel = uilabel(app.ActiveJointPanel);
            app.LinearRailLabel.Text = 'Linear Rail';
            app.BaseJointLabel = uilabel(app.ActiveJointPanel);
            app.BaseJointLabel.Text = 'Base Joint';
            app.ForearmJointLabel = uilabel(app.ActiveJointPanel);
            app.ForearmJointLabel.Text = 'Forearm Joint';
            app.ReararmJointLabel = uilabel(app.ActiveJointPanel);
            app.ReararmJointLabel.Text = 'Reararm Joint';
            app.ServoLabel = uilabel(app.ActiveJointPanel);
            app.ServoLabel.Text = 'Servo';
            app.LinearLamp = uilamp(app.ActiveJointPanel);
            app.Panel = uipanel(app.RobotControlTab);
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = 'dobot.png';
            app.Image3 = uiimage(app.Panel);
            app.Image3.ImageSource = 'rail.jpg';
            app.LinearRailLabel_2 = uilabel(app.Panel);
            app.LinearRailLabel_2.Text = 'Linear Rail';
            app.ForearmJointLabel_2 = uilabel(app.Panel);
            app.ForearmJointLabel_2.Text = 'Forearm Joint';
            app.ServoLabel_2 = uilabel(app.Panel);
            app.ServoLabel_2.Text = 'Servo';
            app.ReararmJointLabel_2 = uilabel(app.Panel);
            app.ReararmJointLabel_2.Text = 'Reararm Joint';
            app.BaseJointLabel_2 = uilabel(app.Panel);
            app.BaseJointLabel_2.Text = 'Base Joint';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_kinect

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @workspaceInit)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end